classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TextArea_2          matlab.ui.control.TextArea
        TextArea            matlab.ui.control.TextArea
        DiceRoll6           matlab.ui.control.Image
        DiceRoll5           matlab.ui.control.Image
        DiceRoll4           matlab.ui.control.Image
        DiceRoll3           matlab.ui.control.Image
        DiceRoll2           matlab.ui.control.Image
        DiceRoll1           matlab.ui.control.Image
        PlayerButton_2      matlab.ui.control.Button
        PlayerButton        matlab.ui.control.Button
        ExitGameButton      matlab.ui.control.Button
        NewGameButton       matlab.ui.control.Button
        IllPassButton       matlab.ui.control.Button
        HitMeButton         matlab.ui.control.Button
        TitleArt            matlab.ui.control.Image
        Image2_2            matlab.ui.control.Image
        Image_2             matlab.ui.control.Image
        Image2              matlab.ui.control.Image
        Image               matlab.ui.control.Image
        WINNERPlayer1Label  matlab.ui.control.Label
        TextArea_3          matlab.ui.control.TextArea
        DiceRoll6_8         matlab.ui.control.Image
        DiceRoll6_7         matlab.ui.control.Image
        DiceRoll6_6         matlab.ui.control.Image
        DiceRoll6_5         matlab.ui.control.Image
        DiceRoll6_4         matlab.ui.control.Image
        DiceRoll6_3         matlab.ui.control.Image
        DiceRoll6_2         matlab.ui.control.Image
        DiceRoll5_8         matlab.ui.control.Image
        DiceRoll5_7         matlab.ui.control.Image
        DiceRoll5_6         matlab.ui.control.Image
        DiceRoll5_5         matlab.ui.control.Image
        DiceRoll5_4         matlab.ui.control.Image
        DiceRoll5_3         matlab.ui.control.Image
        DiceRoll5_2         matlab.ui.control.Image
        DiceRoll4_8         matlab.ui.control.Image
        DiceRoll4_7         matlab.ui.control.Image
        DiceRoll4_6         matlab.ui.control.Image
        DiceRoll4_5         matlab.ui.control.Image
        DiceRoll4_4         matlab.ui.control.Image
        DiceRoll4_3         matlab.ui.control.Image
        DiceRoll4_2         matlab.ui.control.Image
        DiceRoll3_8         matlab.ui.control.Image
        DiceRoll3_7         matlab.ui.control.Image
        DiceRoll3_6         matlab.ui.control.Image
        DiceRoll3_5         matlab.ui.control.Image
        DiceRoll3_4         matlab.ui.control.Image
        DiceRoll3_3         matlab.ui.control.Image
        DiceRoll3_2         matlab.ui.control.Image
        DiceRoll2_8         matlab.ui.control.Image
        DiceRoll2_7         matlab.ui.control.Image
        DiceRoll2_6         matlab.ui.control.Image
        DiceRoll2_5         matlab.ui.control.Image
        DiceRoll2_4         matlab.ui.control.Image
        DiceRoll2_3         matlab.ui.control.Image
        DiceRoll2_2         matlab.ui.control.Image
        DiceRoll1_8         matlab.ui.control.Image
        DiceRoll1_7         matlab.ui.control.Image
        DiceRoll1_6         matlab.ui.control.Image
        DiceRoll1_5         matlab.ui.control.Image
        DiceRoll1_4         matlab.ui.control.Image
        DiceRoll1_3         matlab.ui.control.Image
        DiceRoll1_2         matlab.ui.control.Image
    end

    
    properties (Access = private)
        p1Points
        p2Points
        winner
        % Description
    end
    
    methods (Access = private)
        
        function results = showDice(roll,numDie)
            % SHOWDICE
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      app.IllPassButton.Visible = false;
      app.HitMeButton.Visible = false;   
      app.NewGameButton.Visible = false;
      app.DiceRoll1.Visible = false;
      app.DiceRoll2.Visible = false;
      app.DiceRoll3.Visible = false;
      app.DiceRoll4.Visible = false;
      app.DiceRoll5.Visible = false;
      app.DiceRoll6.Visible = false;
      app.DiceRoll1_2.Visible = false;
      app.DiceRoll2_2.Visible = false;
      app.DiceRoll3_2.Visible = false;
      app.DiceRoll4_2.Visible = false;
      app.DiceRoll5_2.Visible = false;
      app.DiceRoll6_2.Visible = false;
      %app.EndScreen.Visible = false;
      app.DiceRoll1_3.Visible = false;
      app.DiceRoll2_3.Visible = false;
      app.DiceRoll3_3.Visible = false;
      app.DiceRoll4_3.Visible = false;
      app.DiceRoll5_3.Visible = false;
      app.DiceRoll6_3.Visible = false;
      app.DiceRoll1_4.Visible = false;
      app.DiceRoll2_4.Visible = false;
      app.DiceRoll3_4.Visible = false;
      app.DiceRoll4_4.Visible = false;
      app.DiceRoll5_4.Visible = false;
      app.DiceRoll6_4.Visible = false;
      app.DiceRoll1_5.Visible = false;
      app.DiceRoll2_5.Visible = false;
      app.DiceRoll3_5.Visible = false;
      app.DiceRoll4_5.Visible = false;
      app.DiceRoll5_5.Visible = false;
      app.DiceRoll6_5.Visible = false;
      app.DiceRoll1_6.Visible = false;
      app.DiceRoll2_6.Visible = false;
      app.DiceRoll3_6.Visible = false;
      app.DiceRoll4_6.Visible = false;
      app.DiceRoll5_6.Visible = false;
      app.DiceRoll6_6.Visible = false;
      app.DiceRoll1_7.Visible = false;
      app.DiceRoll2_7.Visible = false;
      app.DiceRoll3_7.Visible = false;
      app.DiceRoll4_7.Visible = false;
      app.DiceRoll5_7.Visible = false;
      app.DiceRoll6_7.Visible = false;
      app.DiceRoll1_8.Visible = false;
      app.DiceRoll2_8.Visible = false;
      app.DiceRoll3_8.Visible = false;
      app.DiceRoll4_8.Visible = false;
      app.DiceRoll5_8.Visible = false;
      app.DiceRoll6_8.Visible = false;
      app.p1Points = 0;
      app.p2Points = -1;
        end

        % Button pushed function: PlayerButton
        function SinglePlayer(app, event)
            app.PlayerButton.Visible = 'off';
            app.PlayerButton_2.Visible = 'off';
            app.Image.Visible = 'off';
            app.Image_2.Visible = 'off';
            app.Image2.Visible = 'off';
            app.Image2_2.Visible = 'off';
            app.TitleArt.Visible = 'off';
            app.ExitGameButton.Visible = 'off';
            app.NewGameButton.Visible = 'on';
            app.IllPassButton.Visible = 'on';
            app.HitMeButton.Visible = 'on';
            app.TextArea.Visible = 'on';
            
        end

        % Button pushed function: PlayerButton_2
        function Multiplayer(app, event)
            app.PlayerButton.Visible = 'off';
            app.PlayerButton_2.Visible = 'off';
            app.Image.Visible = 'off';
            app.Image_2.Visible = 'off';
            app.Image2.Visible = 'off';
            app.Image2_2.Visible = 'off';
            app.TitleArt.Visible = 'off';
            app.ExitGameButton.Visible = 'off';
            app.NewGameButton.Visible = 'on';
            app.IllPassButton.Visible = 'on';
            app.HitMeButton.Visible = 'on';
            app.TextArea.Visible = 'on';
        end

        % Button pushed function: ExitGameButton
        function ExitGameButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
      app.IllPassButton.Visible = false;
      app.HitMeButton.Visible = false;   
      app.NewGameButton.Visible = false;
      app.DiceRoll1.Visible = false;
      app.DiceRoll2.Visible = false;
      app.DiceRoll3.Visible = false;
      app.DiceRoll4.Visible = false;
      app.DiceRoll5.Visible = false;
      app.DiceRoll6.Visible = false;
      app.EndScreen.Visible = false;
      app.PlayerButton.Visible = 'on';
            app.PlayerButton_2.Visible = 'on';
            app.Image.Visible = 'on';
            app.Image_2.Visible = 'on';
            app.Image2.Visible = 'on';
            app.Image2_2.Visible = 'on';
            app.TitleArt.Visible = 'on';
            app.ExitGameButton.Visible = 'on';
            app.TextArea.Value = num2str(0);
            app.p1Points = 0;
            app.TextArea.Visible = 'off';
        end

        % Button pushed function: HitMeButton
        function HitMeButtonPushed(app, event)
         randomDie = GameCalc.diceRoll();
         
         % persistent points;
         points = 0;

         if app.p1Points > 12
             app.p1Points = 0;
             app.winner = GameCalc.scoreCalc(app.p1Points, app.p2Points);
            app.IllPassButton.Visible = false;
      app.HitMeButton.Visible = false;   
      app.DiceRoll1.Visible = false;
      app.DiceRoll2.Visible = false;
      app.DiceRoll3.Visible = false;
      app.DiceRoll4.Visible = false;
      app.DiceRoll5.Visible = false;
      app.DiceRoll6.Visible = false;
      app.textArea_2.Value = num2str(GameCalc.dealerVal());
      app.textArea_3.Value = "Winner: " + num2str(app.winner);
         elseif app.p1Points == 12
             app.winner = GameCalc.scoreCalc(app.p1Points, app.p2Points);
        app.IllPassButton.Visible = false;
      app.HitMeButton.Visible = false;   
      app.DiceRoll1.Visible = false;
      app.DiceRoll2.Visible = false;
      app.DiceRoll3.Visible = false;
      app.DiceRoll4.Visible = false;
      app.DiceRoll5.Visible = false;
      app.DiceRoll6.Visible = false;
      app.textArea_2.Value = num2str(GameCalc.dealerVal) ;
         else
            switch randomDie
               case 1
               app.DiceRoll1.Visible = true;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;
               points = points + 1;

               case 2
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = true;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;
               points = points + 2;
               case 3
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = true;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;
               points = points + 3;
               case 4
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = true;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = false;
               points = points + 4;
               case 5
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = true;
               app.DiceRoll6.Visible = false;
               points = points + 5;
               case 6
               app.DiceRoll1.Visible = false;
               app.DiceRoll2.Visible = false;
               app.DiceRoll3.Visible = false;
               app.DiceRoll4.Visible = false;
               app.DiceRoll5.Visible = false;
               app.DiceRoll6.Visible = true;
               points = points + 6;

            end
            app.p1Points = app.p1Points + points;
            app.TextArea.Value = num2str(app.p1Points);
         end
         
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiceRoll1_2 = uiimage(app.UIFigure);
            app.DiceRoll1_2.ImageSource = '220834 (1) (1).png';
            app.DiceRoll1_3 = uiimage(app.UIFigure);
            app.DiceRoll1_3.ImageSource = '220834 (1) (1).png';
            app.DiceRoll1_4 = uiimage(app.UIFigure);
            app.DiceRoll1_4.ImageSource = '220834 (1) (1).png';
            app.DiceRoll1_5 = uiimage(app.UIFigure);
            app.DiceRoll1_5.ImageSource = '220834 (1) (1).png';
            app.DiceRoll1_6 = uiimage(app.UIFigure);
            app.DiceRoll1_6.ImageSource = '220834 (1) (1).png';
            app.DiceRoll1_7 = uiimage(app.UIFigure);
            app.DiceRoll1_7.ImageSource = '220834 (1) (1).png';
            app.DiceRoll1_8 = uiimage(app.UIFigure);
            app.DiceRoll1_8.ImageSource = '220834 (1) (1).png';
            app.DiceRoll2_2 = uiimage(app.UIFigure);
            app.DiceRoll2_2.ImageSource = '220835 (1).png';
            app.DiceRoll2_3 = uiimage(app.UIFigure);
            app.DiceRoll2_3.ImageSource = '220835 (1).png';
            app.DiceRoll2_4 = uiimage(app.UIFigure);
            app.DiceRoll2_4.ImageSource = '220835 (1).png';
            app.DiceRoll2_5 = uiimage(app.UIFigure);
            app.DiceRoll2_5.ImageSource = '220835 (1).png';
            app.DiceRoll2_6 = uiimage(app.UIFigure);
            app.DiceRoll2_6.ImageSource = '220835 (1).png';
            app.DiceRoll2_7 = uiimage(app.UIFigure);
            app.DiceRoll2_7.ImageSource = '220835 (1).png';
            app.DiceRoll2_8 = uiimage(app.UIFigure);
            app.DiceRoll2_8.ImageSource = '220835 (1).png';
            app.DiceRoll3_2 = uiimage(app.UIFigure);
            app.DiceRoll3_2.ImageSource = '220836 (1).png';
            app.DiceRoll3_3 = uiimage(app.UIFigure);
            app.DiceRoll3_3.ImageSource = '220836 (1).png';
            app.DiceRoll3_4 = uiimage(app.UIFigure);
            app.DiceRoll3_4.ImageSource = '220836 (1).png';
            app.DiceRoll3_5 = uiimage(app.UIFigure);
            app.DiceRoll3_5.ImageSource = '220836 (1).png';
            app.DiceRoll3_6 = uiimage(app.UIFigure);
            app.DiceRoll3_6.ImageSource = '220836 (1).png';
            app.DiceRoll3_7 = uiimage(app.UIFigure);
            app.DiceRoll3_7.ImageSource = '220836 (1).png';
            app.DiceRoll3_8 = uiimage(app.UIFigure);
            app.DiceRoll3_8.ImageSource = '220836 (1).png';
            app.DiceRoll4_2 = uiimage(app.UIFigure);
            app.DiceRoll4_2.ImageSource = '220837 (1).png';
            app.DiceRoll4_3 = uiimage(app.UIFigure);
            app.DiceRoll4_3.ImageSource = '220837 (1).png';
            app.DiceRoll4_4 = uiimage(app.UIFigure);
            app.DiceRoll4_4.ImageSource = '220837 (1).png';
            app.DiceRoll4_5 = uiimage(app.UIFigure);
            app.DiceRoll4_5.ImageSource = '220837 (1).png';
            app.DiceRoll4_6 = uiimage(app.UIFigure);
            app.DiceRoll4_6.ImageSource = '220837 (1).png';
            app.DiceRoll4_7 = uiimage(app.UIFigure);
            app.DiceRoll4_7.ImageSource = '220837 (1).png';
            app.DiceRoll4_8 = uiimage(app.UIFigure);
            app.DiceRoll4_8.ImageSource = '220837 (1).png';
            app.DiceRoll5_2 = uiimage(app.UIFigure);
            app.DiceRoll5_2.ImageSource = '220838 (1).png';
            app.DiceRoll5_3 = uiimage(app.UIFigure);
            app.DiceRoll5_3.ImageSource = '220838 (1).png';
            app.DiceRoll5_4 = uiimage(app.UIFigure);
            app.DiceRoll5_4.ImageSource = '220838 (1).png';
            app.DiceRoll5_5 = uiimage(app.UIFigure);
            app.DiceRoll5_5.ImageSource = '220838 (1).png';
            app.DiceRoll5_6 = uiimage(app.UIFigure);
            app.DiceRoll5_6.ImageSource = '220838 (1).png';
            app.DiceRoll5_7 = uiimage(app.UIFigure);
            app.DiceRoll5_7.ImageSource = '220838 (1).png';
            app.DiceRoll5_8 = uiimage(app.UIFigure);
            app.DiceRoll5_8.ImageSource = '220838 (1).png';
            app.DiceRoll6_2 = uiimage(app.UIFigure);
            app.DiceRoll6_2.ImageSource = '220839 (1).png';
            app.DiceRoll6_3 = uiimage(app.UIFigure);
            app.DiceRoll6_3.ImageSource = '220839 (1).png';
            app.DiceRoll6_4 = uiimage(app.UIFigure);
            app.DiceRoll6_4.ImageSource = '220839 (1).png';
            app.DiceRoll6_5 = uiimage(app.UIFigure);
            app.DiceRoll6_5.ImageSource = '220839 (1).png';
            app.DiceRoll6_6 = uiimage(app.UIFigure);
            app.DiceRoll6_6.ImageSource = '220839 (1).png';
            app.DiceRoll6_7 = uiimage(app.UIFigure);
            app.DiceRoll6_7.ImageSource = '220839 (1).png';
            app.DiceRoll6_8 = uiimage(app.UIFigure);
            app.DiceRoll6_8.ImageSource = '220839 (1).png';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.WINNERPlayer1Label = uilabel(app.UIFigure);
            app.WINNERPlayer1Label.Text = 'WINNER = Player 1';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '1187eb9cfb72e78e84d87f283efc2f30.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'flip.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = '1187eb9cfb72e78e84d87f283efc2f30.png';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'flip.png';
            app.TitleArt = uiimage(app.UIFigure);
            app.TitleArt.ImageSource = 'Untitled drawing.png';
            app.HitMeButton = uibutton(app.UIFigure, 'push');
            app.HitMeButton.ButtonPushedFcn = createCallbackFcn(app, @HitMeButtonPushed, true);
            app.HitMeButton.Text = 'Hit Me!';
            app.IllPassButton = uibutton(app.UIFigure, 'push');
            app.IllPassButton.Text = 'I''ll Pass! ';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.ExitGameButton = uibutton(app.UIFigure, 'push');
            app.ExitGameButton.ButtonPushedFcn = createCallbackFcn(app, @ExitGameButtonPushed, true);
            app.ExitGameButton.Text = 'Exit Game';
            app.PlayerButton = uibutton(app.UIFigure, 'push');
            app.PlayerButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePlayer, true);
            app.PlayerButton.Text = '1 Player';
            app.PlayerButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayerButton_2.ButtonPushedFcn = createCallbackFcn(app, @Multiplayer, true);
            app.PlayerButton_2.Text = '2 Player';
            app.DiceRoll1 = uiimage(app.UIFigure);
            app.DiceRoll1.ImageSource = '220834 (1) (1).png';
            app.DiceRoll2 = uiimage(app.UIFigure);
            app.DiceRoll2.ImageSource = '220835 (1).png';
            app.DiceRoll3 = uiimage(app.UIFigure);
            app.DiceRoll3.ImageSource = '220836 (1).png';
            app.DiceRoll4 = uiimage(app.UIFigure);
            app.DiceRoll4.ImageSource = '220837 (1).png';
            app.DiceRoll5 = uiimage(app.UIFigure);
            app.DiceRoll5.ImageSource = '220838 (1).png';
            app.DiceRoll6 = uiimage(app.UIFigure);
            app.DiceRoll6.ImageSource = '220839 (1).png';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea_2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end